classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GuardarcomotxtButton         matlab.ui.control.Button
        DetenerButton                matlab.ui.control.Button
        InicioButton                 matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        BuscarActualizacionesButton  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.s = serialport("/dev/ttyACM1",9600);
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            global parar datos voltaje const
            app.s = serialport("/dev/ttyACM1",9600);
            parar = 0;
            muestras = 50;
            voltaje = 0;
            contador = 1;
            while contador <= muestras
                if parar == 1
                    break
                else
                    valorADC = str2double(readline(app.s));
                    const(contador) = 5;
                    voltaje(contador) = valorADC(1)*5/1023;
                    plot(app.UIAxes,voltaje)
                    app.UIAxes.XLim = [0 contador+50];
                    app.UIAxes.YLim = [0 5.1];
                    tiempo(contador) = contador;
                    datos = [voltaje;tiempo];
                    %app.UI
                    contador = contador + 1;
                end
            end

        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            global parar
            parar = 1;
        end

        % Button pushed function: GuardarcomotxtButton
        function GuardarcomotxtButtonPushed(app, event)
             global datos
             writematrix(datos','datos.txt')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BuscarActualizacionesButton = uibutton(app.UIFigure, 'push');
            app.BuscarActualizacionesButton.Text = {'Buscar '; 'Actualizaciones'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'updatelogo.png';
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.GuardarcomotxtButton = uibutton(app.UIFigure, 'push');
            app.GuardarcomotxtButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarcomotxtButtonPushed, true);
            app.GuardarcomotxtButton.Text = {'Guardar '; 'como .txt'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end