classdef app_offline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField_2                     matlab.ui.control.EditField
        GuardarButton                   matlab.ui.control.Button
        AnalisisECGButton               matlab.ui.control.Button
        AplicarFFTdatosfiltradosButton  matlab.ui.control.Button
        CerrarButton                    matlab.ui.control.Button
        AplicarFFTButton                matlab.ui.control.Button
        FiltroenTiemporealButton        matlab.ui.control.Button
        AplicarFiltroButton             matlab.ui.control.Button
        RespuestaButton                 matlab.ui.control.Button
        AbirtxtButton                   matlab.ui.control.Button
        DisearButton                    matlab.ui.control.Button
        RefrescarButton                 matlab.ui.control.Button
        ListBox2                        matlab.ui.control.ListBox
        EditField                       matlab.ui.control.EditField
        ListBox                         matlab.ui.control.ListBox
        TextArea                        matlab.ui.control.TextArea
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             global datos datos_generados num_datos delay datos2...
                 activador activador2
             activador = 0;
             activador2 = 0;
             app.GuardarButton.Enable =0;
             app.EditField_2.Value = "datosFiltr.txt"; 
             app.AplicarFFTButton.Enable=0;
             app.ListBox.Enable = 0;
             app.ListBox2.Enable = 0;
             app.DisearButton.Enable = 0;
             app.AplicarFiltroButton.Enable = 0;
             app.RefrescarButton.Enable = 0;
             app.RespuestaButton.Enable = 0;
             app.AplicarFiltroButton.Enable =0;
             app.AplicarFFTdatosfiltradosButton.Enable = 0;
             dat_prev = exist('datos_generados');
             if dat_prev ~= 0
                 if datos_generados == 1
                      tx_datos = "Se han detectado datos recientemente adquiridos."+newline+...
                          "[info] " + "Numero de datos: " + convertCharsToStrings(num_datos)+...
                          ". Frecuencia de muestreo: " + string(1000/delay) +" Hz.";
                      datos2 = transpose(datos);
                      activador = 1;
                 else
                     tx_datos = "No se han detectado datos recientemente adquiridos.";
                 end
             end
             if dat_prev == 0
                 tx_datos = "No se han detectado datos recientemente adquiridos.";
             end
             app.TextArea.Value = "[Datos] " + tx_datos+...
                         newline + "[Tarea] " + convertCharsToStrings(app.ListBox.Value);
             %% activamos la lista de tareas
             if activador == 1 || activador2 == 1
                 app.ListBox.Enable = 1;
                 app.AplicarFFTButton.Enable=1;
             else
                 app.ListBox.Enable = 0;
             end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            if contains(app.ListBox.Value, 'Diseño del filtro')
                app.DisearButton.Enable = 1;
                app.RefrescarButton.Enable = 1;
                app.ListBox2.Enable = 1;
                app.AplicarFFTButton.Enable=0;
                app.AplicarFFTdatosfiltradosButton.Enable = 0;
            end
            if contains(app.ListBox.Value,'Analisis frecuencial')
                app.ListBox2.Enable = 0;
                app.DisearButton.Enable = 0;
                app.AplicarFiltroButton.Enable = 0;
                app.RefrescarButton.Enable = 0;
                app.RespuestaButton.Enable = 0;
                app.AplicarFiltroButton.Enable =0;
                app.AplicarFFTButton.Enable=1;
            end
        end

        % Button pushed function: RefrescarButton
        function RefrescarButtonPushed(app, event)
            arch = dir('*.mat');
            nombres=[{arch().name}];
            app.ListBox2.Items = nombres;
            app.AplicarFiltroButton.Enable =1;
            app.RespuestaButton.Enable = 1;
        end

        % Button pushed function: AbirtxtButton
        function AbirtxtButtonPushed(app, event)
            global datos2 activador2 activador
            file = uigetfile('*.txt');
            if file == 0
                activador2 = 0;
                app.TextArea.Value = "[Datos]: No se ha selecionado ningun archivo.";
            else
                activador2 = 1;
                activador = 0;
                app.EditField.Value = file;
                datos2 = readmatrix(file);
                title(app.UIAxes, 'Vista previa');
                plot(app.UIAxes,datos2(:,2),datos2(:,1))
                app.TextArea.Value = "[Datos]: Se han cargado los datos."+newline+...
                    "[Info]: "+  "Numero de datos: " + convertCharsToStrings(length(datos2))+...
                          ". Frecuencia de muestreo: " + string(1/(datos2(2,2)-datos2(1,2))) +" Hz.";
                app.AplicarFFTButton.Enable=1;
            end

            %% activamos la lista de tareas
             if activador == 1 || activador2 == 1
                 app.ListBox.Enable = 1;
             else
                 app.ListBox.Enable = 0;
             end
        end

        % Value changed function: ListBox2
        function ListBox2ValueChanged(app, event)
            global load_filtro
            archivo = app.ListBox2.Value;
            load_filtro = load(archivo);
            
        end

        % Button pushed function: RespuestaButton
        function RespuestaButtonPushed(app, event)
            global load_filtro
            fvtool(load_filtro.Hd);
           
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            global datos2 load_filtro datos_filtro
            y = filter(load_filtro.Hd,datos2(:,1));
            title(app.UIAxes, 'Datos');
            plot(app.UIAxes,datos2(:,2),datos2(:,1))
            

            title(app.UIAxes2, 'Datos filtrados');
            xlabel(app.UIAxes2,'Tiempo [s]')
            ylabel(app.UIAxes2,'Voltage [V]')
            plot(app.UIAxes2,datos2(:,2),y);
            app.GuardarButton.Enable =1;
            app.AplicarFFTdatosfiltradosButton.Enable = 1;
            %%
            datos_filtro = [transpose(y);transpose(datos2(:,2))];
        end

        % Button pushed function: AplicarFFTdatosfiltradosButton
        function AplicarFFTdatosfiltradosButtonPushed(app, event)
            global datos2 load_filtro
            y = filter(load_filtro.Hd,datos2(:,1));
            %establecemos la frecuencia de muestreo Fs
            Fs = 1/(datos2(2,2)-datos2(1,2));
            %t = 0:1/Fs:1-1/Fs;
            N = length(y);
            % realizamos el espectro
            xdft = fft(y);
            xdft = xdft(1:floor(N/2)+1);
            psdx = (1/(Fs*N)) * abs(xdft).^2;
            psdx(2:end-1) = 2*psdx(2:end-1);
            freq = 0:Fs/length(datos2):Fs/2;
            plot(app.UIAxes,freq,10*log10(psdx))
            title(app.UIAxes,'Densidad espectral de potencia (PSD)')
            xlabel(app.UIAxes,'Frecuencia [Hz]')
            ylabel(app.UIAxes,'Potencia/Frecuencia [dB/Hz]')

            app.AplicarFFTdatosfiltradosButton.Enable = 0;

        end

        % Button pushed function: DisearButton
        function DisearButtonPushed(app, event)

            filterDesigner;

        end

        % Button pushed function: FiltroenTiemporealButton
        function FiltroenTiemporealButtonPushed(app, event)
            app_offline_filtro;
        end

        % Button pushed function: AplicarFFTButton
        function AplicarFFTButtonPushed(app, event)
            global datos2
            %establecemos la frecuencia de muestreo Fs
            Fs = 1/(datos2(2,2)-datos2(1,2));
            %t = 0:1/Fs:1-1/Fs;
            N = length(datos2);
            % realizamos el espectro
            xdft = fft(datos2(:,1));
            xdft = xdft(1:floor(N/2)+1);
            psdx = (1/(Fs*N)) * abs(xdft).^2;
            psdx(2:end-1) = 2*psdx(2:end-1);
            freq = 0:Fs/length(datos2):Fs/2;
            plot(app.UIAxes2,freq,10*log10(psdx))
            title(app.UIAxes2,'Densidad espectral de potencia (PSD)')
            xlabel(app.UIAxes2,'Frecuencia [Hz]')
            ylabel(app.UIAxes2,'Potencia/Frecuencia [dB/Hz]')
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: AnalisisECGButton
        function AnalisisECGButtonPushed(app, event)
            app_offline_ECGX;
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
           global datos_filtro
            nombre_data = app.EditField_2.Value;
            writematrix(datos_filtro',nombre_data);
            app.TextArea.Value = ['Datos guardados como ' nombre_data];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Datos')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Seleccione datos')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TextArea = uitextarea(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Diseño del filtro', 'Analisis frecuencial'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Analisis frecuencial';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.ListBox2 = uilistbox(app.UIFigure);
            app.ListBox2.Items = {'No hay filtros cargados'};
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox2ValueChanged, true);
            app.ListBox2.Value = 'No hay filtros cargados';
            app.RefrescarButton = uibutton(app.UIFigure, 'push');
            app.RefrescarButton.ButtonPushedFcn = createCallbackFcn(app, @RefrescarButtonPushed, true);
            app.RefrescarButton.Text = 'Refrescar';
            app.DisearButton = uibutton(app.UIFigure, 'push');
            app.DisearButton.ButtonPushedFcn = createCallbackFcn(app, @DisearButtonPushed, true);
            app.DisearButton.Text = 'Diseñar';
            app.AbirtxtButton = uibutton(app.UIFigure, 'push');
            app.AbirtxtButton.ButtonPushedFcn = createCallbackFcn(app, @AbirtxtButtonPushed, true);
            app.AbirtxtButton.Text = 'Abir (.txt)';
            app.RespuestaButton = uibutton(app.UIFigure, 'push');
            app.RespuestaButton.ButtonPushedFcn = createCallbackFcn(app, @RespuestaButtonPushed, true);
            app.RespuestaButton.Text = 'Respuesta';
            app.AplicarFiltroButton = uibutton(app.UIFigure, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro';
            app.FiltroenTiemporealButton = uibutton(app.UIFigure, 'push');
            app.FiltroenTiemporealButton.ButtonPushedFcn = createCallbackFcn(app, @FiltroenTiemporealButtonPushed, true);
            app.FiltroenTiemporealButton.Text = 'Filtro en Tiempo real';
            app.AplicarFFTButton = uibutton(app.UIFigure, 'push');
            app.AplicarFFTButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFFTButtonPushed, true);
            app.AplicarFFTButton.Text = 'Aplicar FFT';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.AplicarFFTdatosfiltradosButton = uibutton(app.UIFigure, 'push');
            app.AplicarFFTdatosfiltradosButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFFTdatosfiltradosButtonPushed, true);
            app.AplicarFFTdatosfiltradosButton.Text = 'Aplicar FFT (datos filtrados)';
            app.AnalisisECGButton = uibutton(app.UIFigure, 'push');
            app.AnalisisECGButton.ButtonPushedFcn = createCallbackFcn(app, @AnalisisECGButtonPushed, true);
            app.AnalisisECGButton.Text = 'Analisis ECG';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_offline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end