classdef app_offline_ECGX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResetButton                     matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        CerrarButton                    matlab.ui.control.Button
        AplicarButton                   matlab.ui.control.Button
        MinPeakDistanceSEditField       matlab.ui.control.NumericEditField
        MinPeakDistanceSEditFieldLabel  matlab.ui.control.Label
        MinPeakHeightSEditField         matlab.ui.control.NumericEditField
        MinPeakHeightSEditFieldLabel    matlab.ui.control.Label
        MinPeakDistanceREditField       matlab.ui.control.NumericEditField
        MinPeakDistanceREditFieldLabel  matlab.ui.control.Label
        MinPeakHeightREditField         matlab.ui.control.NumericEditField
        MinPeakHeightREditFieldLabel    matlab.ui.control.Label
        min_locmaxQEditField            matlab.ui.control.NumericEditField
        min_locmaxQEditFieldLabel       matlab.ui.control.Label
        min_locminQEditField            matlab.ui.control.NumericEditField
        min_locminQEditFieldLabel       matlab.ui.control.Label
        MinPeakDistanceQEditField       matlab.ui.control.NumericEditField
        MinPeakDistanceQEditFieldLabel  matlab.ui.control.Label
        OndaQLabel                      matlab.ui.control.Label
        OndaSLabel                      matlab.ui.control.Label
        OndaRLabel                      matlab.ui.control.Label
        AbrirtxtButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        DatosLabel                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AplicarButton.Enable = 0;
            %% R
            app.MinPeakHeightREditField.Value = 0.5;
            app.MinPeakDistanceREditField.Value = 300;
            %% S
            app.MinPeakHeightSEditField.Value = 1;
            app.MinPeakDistanceSEditField.Value = 300;
            %% Q
            app.MinPeakDistanceQEditField.Value = 10;
            app.min_locminQEditField.Value = -1.2;
            app.min_locmaxQEditField.Value = -0.9;
        end

        % Button pushed function: AbrirtxtButton
        function AbrirtxtButtonPushed(app, event)
            global datos4
            file = uigetfile('*.txt');
            if file == 0
                app.TextArea.Value = "[Datos]: No se ha selecionado ningun archivo.";
                app.AplicarButton.Enable = 0;
            else

                app.EditField.Value = file;
                datos4 = readmatrix(file);
                muestras = 1:length(datos4(:,2));
                hold(app.UIAxes,'on')
                plot(app.UIAxes,muestras,datos4(:,1))
                app.TextArea.Value = "[Datos]: Se han cargado los datos."+newline+...
                    "[Info]: "+  "Numero de datos: " + convertCharsToStrings(length(datos4))+...
                          ". Frecuencia de muestreo: " + string(1/(datos4(2,2)-datos4(1,2))) +" Hz.";
                app.AplicarButton.Enable = 1;
                hold(app.UIAxes,'off')
            end
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
                global datos4
                t = datos4(:,2);
                ecg = datos4(:,1);
                [~,min_locs] = findpeaks(-ecg,'MinPeakDistance',app.MinPeakDistanceQEditField.Value);
                locs_Qwave = min_locs(ecg(min_locs)>app.min_locminQEditField.Value & ecg(min_locs)<app.min_locmaxQEditField.Value);
                [~,locs_Swave] = findpeaks(-ecg,'MinPeakHeight',app.MinPeakHeightSEditField.Value,...
                                        'MinPeakDistance',app.MinPeakDistanceSEditField.Value);
                [~,locs_Rwave] = findpeaks(ecg,'MinPeakHeight',app.MinPeakHeightREditField.Value,...
                                    'MinPeakDistance',app.MinPeakDistanceREditField.Value);
                %figure
                hold(app.UIAxes,'on')
                %plot(app.UIAxes,t,ecg);
                plot(app.UIAxes,locs_Qwave,ecg(locs_Qwave),'rs','MarkerFaceColor','g')
                plot(app.UIAxes,locs_Rwave,ecg(locs_Rwave),'rv','MarkerFaceColor','r')
                plot(app.UIAxes,locs_Swave,ecg(locs_Swave),'rs','MarkerFaceColor','b')
                legend(app.UIAxes,'ECG con filtro','Onda Q','Onda R','Onda S')
                set_tiemposR = t(locs_Rwave);
                mean_LPM = 0;
                for i = 1:length(set_tiemposR)-1
                    mean_LPM = mean_LPM + (set_tiemposR(i+1)-set_tiemposR(i));
                end
                mean_LPM = mean_LPM/length(set_tiemposR);
                mean_LPM = 60/mean_LPM;
                %% 
                set_tiemposQ = t(locs_Qwave);
                set_tiemposS = t(locs_Swave);
                if isequal(length(set_tiemposS),length(set_tiemposQ))
                    meanQRS = mean(set_tiemposS-set_tiemposQ);
                    dato = string(1000*meanQRS)+" [ms]";
                else
                    dato = "Dimensiones no compatibles";
                end
                app.TextArea.Value = "Frecuencia cardiaca promedio: " + string(mean_LPM) + " LPM"+...
                    newline+"Duracion promedio del Complejo QRS: "+dato;
                hold(app.UIAxes,'off')
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app_offline_ECGX;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Analisis ECG')
            xlabel(app.UIAxes, 'Muestras')
            ylabel(app.UIAxes, 'Voltaje [V]')
            zlabel(app.UIAxes, 'Z')
            app.DatosLabel = uilabel(app.UIFigure);
            app.DatosLabel.Text = 'Datos';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.AbrirtxtButton = uibutton(app.UIFigure, 'push');
            app.AbrirtxtButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirtxtButtonPushed, true);
            app.AbrirtxtButton.Text = 'Abrir (.txt)';
            app.OndaRLabel = uilabel(app.UIFigure);
            app.OndaRLabel.Text = 'Onda R';
            app.OndaSLabel = uilabel(app.UIFigure);
            app.OndaSLabel.Text = 'Onda S';
            app.OndaQLabel = uilabel(app.UIFigure);
            app.OndaQLabel.Text = 'Onda Q';
            app.MinPeakDistanceQEditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakDistanceQEditFieldLabel.Text = 'MinPeakDistance Q';
            app.MinPeakDistanceQEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakDistanceQEditField.Value = 20;
            app.min_locminQEditFieldLabel = uilabel(app.UIFigure);
            app.min_locminQEditFieldLabel.Text = 'min_loc (min) Q';
            app.min_locminQEditField = uieditfield(app.UIFigure, 'numeric');
            app.min_locminQEditField.Value = -1.1;
            app.min_locmaxQEditFieldLabel = uilabel(app.UIFigure);
            app.min_locmaxQEditFieldLabel.Text = 'min_loc (max) Q';
            app.min_locmaxQEditField = uieditfield(app.UIFigure, 'numeric');
            app.min_locmaxQEditField.Value = -0.2;
            app.MinPeakHeightREditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakHeightREditFieldLabel.Text = 'MinPeakHeight R';
            app.MinPeakHeightREditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakHeightREditField.Value = 0.5;
            app.MinPeakDistanceREditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakDistanceREditFieldLabel.Text = 'MinPeakDistance R';
            app.MinPeakDistanceREditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakDistanceREditField.Value = 300;
            app.MinPeakHeightSEditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakHeightSEditFieldLabel.Text = 'MinPeakHeight S';
            app.MinPeakHeightSEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakHeightSEditField.Value = 1;
            app.MinPeakDistanceSEditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakDistanceSEditFieldLabel.Text = 'MinPeakDistance S';
            app.MinPeakDistanceSEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakDistanceSEditField.Value = 300;
            app.AplicarButton = uibutton(app.UIFigure, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.TextArea = uitextarea(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_offline_ECGX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end