classdef app_offline_filtro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HabilitarDrawnowCheckBox  matlab.ui.control.CheckBox
        VentanaEditField          matlab.ui.control.NumericEditField
        VentanaEditFieldLabel     matlab.ui.control.Label
        BuscarpuertoButton        matlab.ui.control.Button
        Ylim_maxEditField         matlab.ui.control.NumericEditField
        Ylim_maxEditFieldLabel    matlab.ui.control.Label
        Ylim_minEditField         matlab.ui.control.NumericEditField
        Ylim_minEditFieldLabel    matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        CerrarButton              matlab.ui.control.Button
        GuardartxtButton          matlab.ui.control.Button
        DetenerButton             matlab.ui.control.Button
        IniciarButton             matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        DatosLabel                matlab.ui.control.Label
        RespuestaButton           matlab.ui.control.Button
        DisearButton              matlab.ui.control.Button
        RefrescarButton           matlab.ui.control.Button
        ListBox                   matlab.ui.control.ListBox
        SelecciondelfiltroLabel   matlab.ui.control.Label
        TiemposEditField          matlab.ui.control.NumericEditField
        TiemposEditFieldLabel     matlab.ui.control.Label
        MuestrasEditField_2       matlab.ui.control.NumericEditField
        MuestrasEditField_2Label  matlab.ui.control.Label
        DelaymsEditField_2        matlab.ui.control.NumericEditField
        DelaymsEditField_2Label   matlab.ui.control.Label
        ConfiguraciondelDispositivoLabel  matlab.ui.control.Label
        BaudRateDropDown          matlab.ui.control.DropDown
        BaudRateDropDownLabel     matlab.ui.control.Label
        PuertoEditField           matlab.ui.control.EditField
        PuertoEditFieldLabel      matlab.ui.control.Label
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global delay2 muestras2 baud_r2 baud_rNUM2 puerto2 puerto2_d refrescar_d drawnow_c
            %% condicionoes
            drawnow_c =0;
            refrescar_d = 0;
            app.VentanaEditField.Value = 3;
            app.EditField.Value="datos_F0.txt";
            app.HabilitarDrawnowCheckBox.Value = false;
            %% dispositivo
            delay2 = 10;
            muestras2 = 3000;
            app.DelaymsEditField_2.Value = delay2;
            app.MuestrasEditField_2.Value = muestras2;
            app.TiemposEditField.Value = delay2*muestras2/1000;
            
            puerto2 = app.PuertoEditField.Value;
            baud_r2 = app.BaudRateDropDown.Value;
            baud_rNUM2 = str2num(baud_r2);
            %% botones
            app.IniciarButton.Enable = 0;
            app.DetenerButton.Enable = 0;
            app.GuardartxtButton.Enable = 0; 
            app.RespuestaButton.Enable = 0;
            %% ylims
            app.Ylim_maxEditField.Value = 3;
            app.Ylim_minEditField.Value = -3;
            %% busqueda del dispositivo
            dispositivo  = serialportlist;
            if isempty(dispositivo)
                app.TextArea.Value = "Ningun dispositivo conectado";
                app.BaudRateDropDown.Enable =0;
                app.DelaymsEditField_2.Enable=0;
                app.MuestrasEditField_2.Enable=0;
                app.TiemposEditField.Enable=0;
                app.PuertoEditField.Value= '';
                puerto2_d = 0;
            else 
                app.TextArea.Value = "Dispositivo encontrado en el puerto "+ dispositivo;
                app.BaudRateDropDown.Enable =1;
                app.DelaymsEditField_2.Enable=1;
                app.MuestrasEditField_2.Enable=1;
                app.TiemposEditField.Enable=1;
                app.PuertoEditField.Value= dispositivo;
                puerto2_d = 1;
            end         
        end

        % Button pushed function: BuscarpuertoButton
        function BuscarpuertoButtonPushed(app, event)
            global puerto2_d refrescar_d
            dispositivo  = serialportlist;
            if isempty(dispositivo)
                app.TextArea.Value = "Ningun dispositivo encontrado";
                app.BaudRateDropDown.Enable =0;
                app.DelaymsEditField_2.Enable=0;
                app.MuestrasEditField_2.Enable=0;
                app.TiemposEditField.Enable=0;
                app.PuertoEditField.Value= '';
                puerto2_d = 0;
            else 
                app.TextArea.Value = "Dispositivo encontrado en el puerto "+ dispositivo;
                app.BaudRateDropDown.Enable =1;
                app.DelaymsEditField_2.Enable=1;
                app.MuestrasEditField_2.Enable=1;
                app.TiemposEditField.Enable=1;
                app.PuertoEditField.Value= dispositivo;
                puerto2_d = 1;
            end
            if puerto2_d == 1 && refrescar_d ==1
                app.IniciarButton.Enable = 1;
            else
                app.IniciarButton.Enable = 0;
            end
        end

        % Value changed function: PuertoEditField
        function PuertoEditFieldValueChanged(app, event)
            global puerto2
            puerto2= app.PuertoEditField.Value;            
        end

        % Value changed function: BaudRateDropDown
        function BaudRateDropDownValueChanged(app, event)
            value = app.BaudRateDropDown.Value;
            global baud_r2 baud_rNUM2
            baud_r2 = app.BaudRateDropDown.Value;
            baud_rNUM2 = str2num(baud_r2);
        end

        % Value changed function: DelaymsEditField_2
        function DelaymsEditField_2ValueChanged(app, event)
            global delay2 muestras2
            delay2 = app.DelaymsEditField_2.Value; 
            app.TiemposEditField.Value = delay2*muestras2/1000;
            
        end

        % Value changed function: MuestrasEditField_2
        function MuestrasEditField_2ValueChanged(app, event)
            global delay2 muestras2
            muestras2 = app.MuestrasEditField_2.Value;
            app.TiemposEditField.Value = delay2*muestras2/1000;
            
        end

        % Button pushed function: DisearButton
        function DisearButtonPushed(app, event)
            filterDesigner;
        end

        % Button pushed function: RefrescarButton
        function RefrescarButtonPushed(app, event)
            global load_filtro2 puerto2_d refrescar_d
            refrescar_d = 1;
            app.RespuestaButton.Enable = 1;
            arch2 = dir('*.mat');
            nombres2=[{arch2().name}];
            app.ListBox.Items = nombres2;
            archivo = app.ListBox.Value;
            app.TextArea.Value = "Filtro cargado: " + archivo;
            
            if puerto2_d == 1 &&  refrescar_d ==1
                app.IniciarButton.Enable = 1;
            else
                app.IniciarButton.Enable = 0;
            end
            %% 
            load_filtro2=load(archivo);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            global load_filtro2
            archivo = app.ListBox.Value;
            load_filtro2=load(archivo);
            app.TextArea.Value = "Filtro cargado: " + archivo;
        end

        % Button pushed function: RespuestaButton
        function RespuestaButtonPushed(app, event)
            global load_filtro2
            fvtool(load_filtro2.Hd);
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            global parar2 puerto2 baud_rNUM2 voltaje2 muestras2 delay2 load_filtro2 datos3 drawnow_c

            app.IniciarButton.Enable = 0;
            app.DetenerButton.Enable = 1;
            app.CerrarButton.Enable = 0;
            app.TextArea.Value = 'Cargando...';
            parar2 = 0;
            pause(1);
            app.TextArea.Value = 'Capturando datos...';
            puerto2 = app.PuertoEditField.Value; 
            app.s = serialport(puerto2,baud_rNUM2);
            pause(2);
            voltaje2 = zeros(1,muestras2);
            tiempo = zeros(1,muestras2);
            contador = 1;
            while contador <= muestras2
                if parar2 == 1
                    break
                else
                    if drawnow_c == 1
                        drawnow limitrate nocallbacks
                        app.HabilitarDrawnowCheckBox.Enable=0;
                        app.TextArea.Value = "[drawnow limitrate] No cierre hasta terminar la captura " + ...
                            "de datos.";
                    end

                    Y_limMIM = app.Ylim_minEditField.Value;
                    Y_limMAX = app.Ylim_maxEditField.Value;
                    valorADC = str2double(readline(app.s));
                    if isnan(valorADC(1))
                        if contador == 1
                            volt = 511.5; %adc = 2.5V
                        else
                            volt = (voltaje2(contador - 1))*1023/5;
                        end
                    else
                        volt = valorADC(1);
                    end
                    voltaje2(contador) = (volt*5/1023) - 2.5;
                    tiempo(contador) = contador*delay2/1000;
                    %title(app.UIAxes, ['Datos del puerto ' puerto2]);
                    app.UIAxes.XLim = [(contador*delay2/1000)-app.VentanaEditField.Value (contador*delay2/1000)];
                    app.UIAxes.YLim = [Y_limMIM Y_limMAX];
                    plot(app.UIAxes,tiempo(1:contador),voltaje2(1:contador))
                    y =filter(load_filtro2.Hd,voltaje2(1:contador));
                    app.UIAxes_2.XLim = [(contador*delay2/1000)-app.VentanaEditField.Value (contador*delay2/1000)];
                    app.UIAxes_2.YLim = [Y_limMIM Y_limMAX];
                    plot(app.UIAxes_2,tiempo(1:contador),y)
                    contador = contador + 1;      
                end
            end
            contador = contador -1;
            num_datos = int2str(contador);
            app.TextArea.Value = ['Captura finalizada, se han capturado ' num_datos ' muestras'];
            parar2 = 1;
            app.DetenerButton.Enable = 0;
            datos3 = [voltaje2(1:contador);tiempo(1:contador);y];
            app.GuardartxtButton.Enable = 1;
            app.CerrarButton.Enable =1;
            app.IniciarButton.Enable=1;
            app.HabilitarDrawnowCheckBox.Enable = 1;
            delete(app.s);
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            global parar2
            parar2 = 1;
        end

        % Button pushed function: GuardartxtButton
        function GuardartxtButtonPushed(app, event)
            global datos3
            nombre_data = app.EditField.Value;
            writematrix(datos3',nombre_data);
            app.TextArea.Value = ['Datos guardados como ' nombre_data];
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            app.delete;
        end

        % Value changed function: HabilitarDrawnowCheckBox
        function HabilitarDrawnowCheckBoxValueChanged(app, event)
            global drawnow_c
            value = app.HabilitarDrawnowCheckBox.Value;
            if value == false
                drawnow_c = 0;
            end
            if value == true
                 drawnow_c = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Datos')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Volt [V]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Datos Filtrados')
            xlabel(app.UIAxes_2, 'Tiempo [s]')
            ylabel(app.UIAxes_2, 'Volt [V]')
            zlabel(app.UIAxes_2, 'Z')
            app.PuertoEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.PuertoEditField = uieditfield(app.UIFigure, 'text');
            app.PuertoEditField.ValueChangedFcn = createCallbackFcn(app, @PuertoEditFieldValueChanged, true);
            app.BaudRateDropDownLabel = uilabel(app.UIFigure);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.UIFigure);
            app.BaudRateDropDown.Items = {'38400', '9600', '19200', '2000000'};
            app.BaudRateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudRateDropDownValueChanged, true);
            app.BaudRateDropDown.Value = '38400';
            app.ConfiguraciondelDispositivoLabel = uilabel(app.UIFigure);
            app.ConfiguraciondelDispositivoLabel.Text = 'Configuracion del Dispositivo';
            app.DelaymsEditField_2Label = uilabel(app.UIFigure);
            app.DelaymsEditField_2Label.Text = 'Delay [ms]';
            app.DelaymsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DelaymsEditField_2.ValueChangedFcn = createCallbackFcn(app, @DelaymsEditField_2ValueChanged, true);
            app.DelaymsEditField_2.Value = 10;
            app.MuestrasEditField_2Label = uilabel(app.UIFigure);
            app.MuestrasEditField_2Label.Text = 'Muestras';
            app.MuestrasEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MuestrasEditField_2.ValueChangedFcn = createCallbackFcn(app, @MuestrasEditField_2ValueChanged, true);
            app.MuestrasEditField_2.Value = 3000;
            app.TiemposEditFieldLabel = uilabel(app.UIFigure);
            app.TiemposEditFieldLabel.Text = 'Tiempo [s]';
            app.TiemposEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiemposEditField.Value = 30;
            app.SelecciondelfiltroLabel = uilabel(app.UIFigure);
            app.SelecciondelfiltroLabel.Text = 'Seleccion del filtro';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'No hay filtros cargados'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'No hay filtros cargados';
            app.RefrescarButton = uibutton(app.UIFigure, 'push');
            app.RefrescarButton.ButtonPushedFcn = createCallbackFcn(app, @RefrescarButtonPushed, true);
            app.RefrescarButton.Text = 'Refrescar';
            app.DisearButton = uibutton(app.UIFigure, 'push');
            app.DisearButton.ButtonPushedFcn = createCallbackFcn(app, @DisearButtonPushed, true);
            app.DisearButton.Text = 'Diseñar';
            app.RespuestaButton = uibutton(app.UIFigure, 'push');
            app.RespuestaButton.ButtonPushedFcn = createCallbackFcn(app, @RespuestaButtonPushed, true);
            app.RespuestaButton.Text = 'Respuesta';
            app.DatosLabel = uilabel(app.UIFigure);
            app.DatosLabel.Text = 'Datos';
            app.TextArea = uitextarea(app.UIFigure);
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.GuardartxtButton = uibutton(app.UIFigure, 'push');
            app.GuardartxtButton.ButtonPushedFcn = createCallbackFcn(app, @GuardartxtButtonPushed, true);
            app.GuardartxtButton.Text = 'Guardar (.txt)';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'datos_F0';
            app.Ylim_minEditFieldLabel = uilabel(app.UIFigure);
            app.Ylim_minEditFieldLabel.Text = 'Ylim_min';
            app.Ylim_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ylim_minEditField.Value = -2.5;
            app.Ylim_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Ylim_maxEditFieldLabel.Text = 'Ylim_max';
            app.Ylim_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ylim_maxEditField.Value = 2.5;
            app.BuscarpuertoButton = uibutton(app.UIFigure, 'push');
            app.BuscarpuertoButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarpuertoButtonPushed, true);
            app.BuscarpuertoButton.Text = 'Buscar puerto';
            app.VentanaEditFieldLabel = uilabel(app.UIFigure);
            app.VentanaEditFieldLabel.Text = 'Ventana';
            app.VentanaEditField = uieditfield(app.UIFigure, 'numeric');
            app.VentanaEditField.Value = 3;
            app.HabilitarDrawnowCheckBox = uicheckbox(app.UIFigure);
            app.HabilitarDrawnowCheckBox.ValueChangedFcn = createCallbackFcn(app, @HabilitarDrawnowCheckBoxValueChanged, true);
            app.HabilitarDrawnowCheckBox.Text = 'Habilitar Drawnow( )';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_offline_filtro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end