classdef app_online < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        VentanaEditField           matlab.ui.control.NumericEditField
        VentanaEditFieldLabel      matlab.ui.control.Label
        PostprocesamientoButton    matlab.ui.control.Button
        Ylim_maxEditField          matlab.ui.control.NumericEditField
        Ylim_maxEditFieldLabel     matlab.ui.control.Label
        Ylim_minEditField          matlab.ui.control.NumericEditField
        Ylim_minEditFieldLabel     matlab.ui.control.Label
        MenuPrincipalButton        matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        TabGroup                   matlab.ui.container.TabGroup
        AdquisicionTab             matlab.ui.container.Tab
        HabilitarDrawnowCheckBox   matlab.ui.control.CheckBox
        GuardarButton              matlab.ui.control.Button
        ARCHIVOtxtEditField        matlab.ui.control.EditField
        DetenerButton              matlab.ui.control.Button
        InicioButton               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        EstadisticaTab             matlab.ui.container.Tab
        CargardatospreviosButton   matlab.ui.control.Button
        GenerarestadisticasButton  matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        GuardarestadisticasButton  matlab.ui.control.Button
        ARCHIVOtxtEditField_3      matlab.ui.control.EditField
        CargartxtButton            matlab.ui.control.Button
        ARCHIVOtxtEditField_2      matlab.ui.control.EditField
        UIAxes2                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        s % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global tabla datos_generados std_c
            app.Ylim_minEditField.Value = -3;
            app.Ylim_maxEditField.Value = 3;
            app.ARCHIVOtxtEditField.Value = 'datos0.txt';
            app.TextArea.Value = '[CAPTURA DE DATOS] Esperando...';
            tabla = zeros(2,3);
            datos_generados = 0;
            std_c = 0;
            app.HabilitarDrawnowCheckBox.Value = false;
            app.VentanaEditField.Value = 3;
            app.GuardarButton.Enable =0;
            app.DetenerButton.Enable =0;
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            app.TextArea.Value = 'Cargando...';
            global parar datos voltaje muestras puerto baud_rNUM delay Y_limMIM...
                Y_limMAX datos_generados num_datos 
            parar = 0;
            %%
            app.InicioButton.Enable = 0;
            app.DetenerButton.Enable = 1;
            app.MenuPrincipalButton.Enable = 0;
            app.GuardarButton.Enable =0;
            app.PostprocesamientoButton.Enable =0;
            %%
            pause(1);
            app.TextArea.Value = 'Capturando datos...';
            app.s = serialport(puerto,baud_rNUM);
            pause(3);
            voltaje = zeros(1,muestras);
            tiempo = zeros(1,muestras);
            contador = 1;
            title(app.UIAxes, ['Datos del puerto ' puerto]);
            while contador <= muestras
                if app.HabilitarDrawnowCheckBox.Value == true
                    app.DetenerButton.Enable =0;
                    app.HabilitarDrawnowCheckBox.Enable =0;
                    app.TextArea.Value =  "[drawnow limitrate] No cierre hasta terminar la captura " + ...
                            "de datos.";
                    drawnow limitrate nocallbacks
                end
                if parar == 1
                    break
                else
                    Y_limMIM = app.Ylim_minEditField.Value;
                    Y_limMAX = app.Ylim_maxEditField.Value;
                    valorADC = str2double(readline(app.s));
                    if isnan(valorADC(1))
                        if contador == 1
                            volt = 511.5; %adc = 2.5V
                        else
                            volt = (voltaje(contador - 1))*1023/5;
                        end
                    else
                        volt = valorADC(1);
                    end
                    voltaje(contador) = (volt*5/1023) - 2.5;
                    tiempo(contador) = contador*delay/1000;
                    app.UIAxes.XLim = [(contador*delay/1000)-app.VentanaEditField.Value (contador*delay/1000)+0];
                    app.UIAxes.YLim = [Y_limMIM Y_limMAX];
                    plot(app.UIAxes,tiempo(1:contador),voltaje(1:contador))
                    contador = contador + 1;
                end
            end
            contador = contador -1;
            datos = [voltaje(1:contador);tiempo(1:contador)];
            num_datos = int2str(contador);
            app.TextArea.Value = ['Captura finalizada, se han capturado ' num_datos ' muestras'];
            parar = 1;
            datos_generados = 1;
            app.HabilitarDrawnowCheckBox.Enable =1;
            app.MenuPrincipalButton.Enable = 1;
            app.GuardarButton.Enable =1;
            app.InicioButton.Enable = 1;
            app.DetenerButton.Enable = 0;
            app.PostprocesamientoButton.Enable =1;

            delete(app.s);
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.TextArea.Value = 'Captura finalizada por el usuario.';
            global parar
            parar = 1;
        end

        % Button pushed function: MenuPrincipalButton
        function MenuPrincipalButtonPushed(app, event)
           global online_c parar
           if parar == 1
                 online_c = 0;
                 app.TextArea.Value = 'Cerrando.';
                 pause(2)
                 app.delete;
           else
               app.TextArea.Value = 'Capturando datos. No se puede finalizar.';
           end
          
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            global datos
            nombre_data = app.ARCHIVOtxtEditField.Value;
            writematrix(datos',nombre_data);
            app.TextArea.Value = ['Datos guardados como ' nombre_data];
        end

        % Button pushed function: CargartxtButton
        function CargartxtButtonPushed(app, event)
            global std_c datos_load
            nombre_load = app.ARCHIVOtxtEditField_2.Value;
            existencia1 = exist([nombre_load '.txt']);
            if existencia1 == 0
               app.TextArea.Value = '[cargar datos] El archivo no existe.';
            else
                 std_c = 1;
                 app.TextArea.Value = 'Datos cargados.';
                 datos_load = readmatrix(nombre_load);
                 plot(app.UIAxes2,datos_load(:,2),datos_load(:,1))
            end
        end

        % Button pushed function: CargardatospreviosButton
        function CargardatospreviosButtonPushed(app, event)
            global datos_generados datos_load std_c datos
            if datos_generados == 0
                 app.TextArea.Value = '[cargar datos] No hay datos previos almacenados.';
            else
                std_c = 2;
                app.TextArea.Value = 'Datos previos cargados.';
                datos_load = transpose(datos);
                plot(app.UIAxes2,datos_load(:,2),datos_load(:,1))
            end
        end

        % Button pushed function: GenerarestadisticasButton
        function GenerarestadisticasButtonPushed(app, event)
            global tabla std_c datos_load
            %nombre_load = app.ARCHIVOtxtEditField_2.Value;
            
            if std_c == 0
                app.TextArea.Value = '[generar estadisticas] El archivo no existe o no ha cargado datos previos.';
                app.UITable.Data = tabla; 
            end
            if std_c == 1
                %app.TextArea.Value = ['Las estadisticas se han generado con el archivo ' app.ARCHIVOtxtEditField_2.Value];
                %datos_load = readmatrix(nombre_load);
                [VmaxX, VmaxY] = max(datos_load(:,1));
                [VminX, VminY] = min(datos_load(:,1));
                tabla = [VmaxX,VminX,0;datos_load(VmaxY,2),datos_load(VminY,2),0];
                app.UITable.Data = tabla; 
            end
            if std_c == 2
                app.TextArea.Value = 'Las estadisticas se han generado con los datos previos.';
                %datos_load = readmatrix(nombre_load);
                [VmaxX, VmaxY] = max(datos_load(:,1));
                [VminX, VminY] = min(datos_load(:,1));
                tabla = [VmaxX,VminX,0;datos_load(VmaxY,2),datos_load(VminY,2),0];
                app.UITable.Data = tabla; 
            end
        end

        % Button pushed function: GuardarestadisticasButton
        function GuardarestadisticasButtonPushed(app, event)
            global tabla
            nombre_data = app.ARCHIVOtxtEditField_3.Value;
            writematrix(tabla',nombre_data);
            app.TextArea.Value = ['Datos guardados como ' nombre_data];
        end

        % Button pushed function: PostprocesamientoButton
        function PostprocesamientoButtonPushed(app, event)
            app_offline;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdquisicionTab = uitab(app.TabGroup);
            app.AdquisicionTab.Title = 'Adquisicion';
            app.UIAxes = uiaxes(app.AdquisicionTab);
            title(app.UIAxes, 'Datos')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Voltaje [V]')
            zlabel(app.UIAxes, 'Z')
            app.InicioButton = uibutton(app.AdquisicionTab, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.DetenerButton = uibutton(app.AdquisicionTab, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.ARCHIVOtxtEditField = uieditfield(app.AdquisicionTab, 'text');
            app.ARCHIVOtxtEditField.Value = 'datos0';
            app.GuardarButton = uibutton(app.AdquisicionTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.HabilitarDrawnowCheckBox = uicheckbox(app.AdquisicionTab);
            app.HabilitarDrawnowCheckBox.Text = 'Habilitar Drawnow( )';
            app.EstadisticaTab = uitab(app.TabGroup);
            app.EstadisticaTab.Title = 'Estadistica';
            app.UIAxes2 = uiaxes(app.EstadisticaTab);
            title(app.UIAxes2, 'Vista previa')
            ylabel(app.UIAxes2, {''; ''})
            zlabel(app.UIAxes2, 'Z')
            app.ARCHIVOtxtEditField_2 = uieditfield(app.EstadisticaTab, 'text');
            app.ARCHIVOtxtEditField_2.Value = 'datos0';
            app.CargartxtButton = uibutton(app.EstadisticaTab, 'push');
            app.CargartxtButton.ButtonPushedFcn = createCallbackFcn(app, @CargartxtButtonPushed, true);
            app.CargartxtButton.Text = 'Cargar (.txt)';
            app.ARCHIVOtxtEditField_3 = uieditfield(app.EstadisticaTab, 'text');
            app.ARCHIVOtxtEditField_3.Value = 'estadisticas0';
            app.GuardarestadisticasButton = uibutton(app.EstadisticaTab, 'push');
            app.GuardarestadisticasButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarestadisticasButtonPushed, true);
            app.GuardarestadisticasButton.Text = 'Guardar estadisticas';
            app.UITable = uitable(app.EstadisticaTab);
            app.UITable.ColumnName = {'Valor maximo'; 'Valor minimo'; 'Pico de frecuencia'};
            app.UITable.RowName = {};
            app.GenerarestadisticasButton = uibutton(app.EstadisticaTab, 'push');
            app.GenerarestadisticasButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarestadisticasButtonPushed, true);
            app.GenerarestadisticasButton.Text = 'Generar estadisticas';
            app.CargardatospreviosButton = uibutton(app.EstadisticaTab, 'push');
            app.CargardatospreviosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatospreviosButtonPushed, true);
            app.CargardatospreviosButton.Text = 'Cargar datos previos';
            app.TextArea = uitextarea(app.UIFigure);
            app.MenuPrincipalButton = uibutton(app.UIFigure, 'push');
            app.MenuPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @MenuPrincipalButtonPushed, true);
            app.MenuPrincipalButton.Text = 'Menu Principal';
            app.Ylim_minEditFieldLabel = uilabel(app.UIFigure);
            app.Ylim_minEditFieldLabel.Text = 'Ylim_min';
            app.Ylim_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ylim_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Ylim_maxEditFieldLabel.Text = 'Ylim_max';
            app.Ylim_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostprocesamientoButton = uibutton(app.UIFigure, 'push');
            app.PostprocesamientoButton.ButtonPushedFcn = createCallbackFcn(app, @PostprocesamientoButtonPushed, true);
            app.PostprocesamientoButton.Text = 'Postprocesamiento';
            app.VentanaEditFieldLabel = uilabel(app.UIFigure);
            app.VentanaEditFieldLabel.Text = 'Ventana';
            app.VentanaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_online

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end