classdef app_online2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YLimEditField             matlab.ui.control.NumericEditField
        YLimEditFieldLabel        matlab.ui.control.Label
        XLimEditField             matlab.ui.control.NumericEditField
        XLimEditFieldLabel        matlab.ui.control.Label
        MenuPrincipalButton       matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        AdqusicionTab             matlab.ui.container.Tab
        archivotxtEditField       matlab.ui.control.EditField
        archivotxtEditFieldLabel  matlab.ui.control.Label
        GuardardatosLabel         matlab.ui.control.Label
        DetenerButton             matlab.ui.control.Button
        InicioButton              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        EstadisticaTab            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        s % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.s = serialport("/dev/ttyACM1",9600);
            %datoss = 1;
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            global parar datos voltaje const muestras puerto
            %app.s = serialport("/dev/ttyACM0",9600);
            parar = 0;
            %muestras = 50;
            voltaje = 0;
            contador = 1;
            while contador <= muestras
                if parar == 1
                    break
                else
                    valorADC = str2double(readline(serialport("/dev/ttyACM0",9600)));
                    const(contador) = 5;
                    voltaje(contador) = valorADC(1)*5/1023;
                    plot(app.UIAxes,voltaje)
                    title(app.UIAxes, puerto)
                    app.UIAxes.XLim = [0 contador+50];
                    app.UIAxes.YLim = [0 5.1];
                    tiempo(contador) = contador;
                    datos = [voltaje;tiempo];
                    %app.UI
                    contador = contador + 1;
                end
            end

        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            global parar
            parar = 1;
        end

        % Callback function
        function GuardarcomotxtButtonPushed(app, event)
             global datos
             writematrix(datos','datos.txt')
        end

        % Button pushed function: MenuPrincipalButton
        function MenuPrincipalButtonPushed(app, event)
            global online_c
            online_c = 0;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdqusicionTab = uitab(app.TabGroup);
            app.AdqusicionTab.Title = 'Adqusicion';
            app.UIAxes = uiaxes(app.AdqusicionTab);
            title(app.UIAxes, 'Datos')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Voltaje [V]')
            zlabel(app.UIAxes, 'Z')
            app.InicioButton = uibutton(app.AdqusicionTab, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.DetenerButton = uibutton(app.AdqusicionTab, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.GuardardatosLabel = uilabel(app.AdqusicionTab);
            app.GuardardatosLabel.Text = 'Guardar datos';
            app.archivotxtEditFieldLabel = uilabel(app.AdqusicionTab);
            app.archivotxtEditFieldLabel.Text = '''archivo''.txt';
            app.archivotxtEditField = uieditfield(app.AdqusicionTab, 'text');
            app.EstadisticaTab = uitab(app.TabGroup);
            app.EstadisticaTab.Title = 'Estadistica';
            app.MenuPrincipalButton = uibutton(app.UIFigure, 'push');
            app.MenuPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @MenuPrincipalButtonPushed, true);
            app.MenuPrincipalButton.Text = 'Menu Principal';
            app.XLimEditFieldLabel = uilabel(app.UIFigure);
            app.XLimEditFieldLabel.Text = 'XLim';
            app.XLimEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLimEditFieldLabel = uilabel(app.UIFigure);
            app.YLimEditFieldLabel.Text = 'YLim';
            app.YLimEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_online2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end