classdef app2_modo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        SeleccioneunmodoButtonGroup  matlab.ui.container.ButtonGroup
        ECGDataButton                matlab.ui.control.RadioButton
        EEGDataButton                matlab.ui.control.RadioButton
        PupilDataButton              matlab.ui.control.RadioButton
        MododeanalisisButton         matlab.ui.control.Button
        MododevisualizacionButton    matlab.ui.control.Button
        Image_3                      matlab.ui.control.Image
        Image_2                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MododevisualizacionButton
        function MododevisualizacionButtonPushed(app, event)
            app.TextArea.Value = num2str(app.ECGDataButton.Value);
            if app.PupilDataButton.Value == 1
                app3_1_mod1_pupil;
                app.delete;
            else
                if app.EEGDataButton.Value == 1
                    app3_2_mod2_EEG;
                    app.delete;
                else
                    if app.ECGDataButton.Value == 1
                    app3_3_mod3_ECG;
                    app.delete;
                    end
                end
            end
            %if app.ECGDataButton.Value == 1
            %    app3_3_mod3_EGC;
            %    app.delete;
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.MododevisualizacionButton = uibutton(app.UIFigure, 'push');
            app.MododevisualizacionButton.ButtonPushedFcn = createCallbackFcn(app, @MododevisualizacionButtonPushed, true);
            app.MododevisualizacionButton.Text = 'Modo de visualizacion';
            app.MododeanalisisButton = uibutton(app.UIFigure, 'push');
            app.MododeanalisisButton.Text = 'Modo de analisis';
            app.SeleccioneunmodoButtonGroup = uibuttongroup(app.UIFigure);
            app.SeleccioneunmodoButtonGroup.Title = 'Seleccione un modo';
            app.PupilDataButton = uiradiobutton(app.SeleccioneunmodoButtonGroup);
            app.PupilDataButton.Text = 'Pupil Data';
            app.PupilDataButton.Value = true;
            app.EEGDataButton = uiradiobutton(app.SeleccioneunmodoButtonGroup);
            app.EEGDataButton.Text = 'EEG Data';
            app.ECGDataButton = uiradiobutton(app.SeleccioneunmodoButtonGroup);
            app.ECGDataButton.Text = 'ECG Data';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_modo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end